classdef MouseVideoBehavioralAnalysisGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        ExporttoCSVButton            matlab.ui.control.Button
        MouseVideoBehavioralAnalysisLabel  matlab.ui.control.Label
        PickVideoDirectoryforExportingButton  matlab.ui.control.Button
        DirectoryChosenLabel         matlab.ui.control.Label
        LengthofVideosinsecondsEditFieldLabel  matlab.ui.control.Label
        LengthofVideos               matlab.ui.control.NumericEditField
        IntervalinsecondsEditFieldLabel  matlab.ui.control.Label
        IntervalInSec                matlab.ui.control.NumericEditField
        NameofOutputFileifexportingLabel  matlab.ui.control.Label
        NameofOutput                 matlab.ui.control.EditField
        Status                       matlab.ui.control.TextArea
        MinimumThresholdForFreezingcmsecEditFieldLabel  matlab.ui.control.Label
        MinimumThresholdForFreezing  matlab.ui.control.NumericEditField
        DisplayPlotButton            matlab.ui.control.Button
        ExportForMLButton            matlab.ui.control.Button
    end


    properties (Access = public)
        PathToDirectory % Description
        FullPath
    end


    methods (Access = private)

        % Button pushed function: DisplayPlotButton
        function DisplayPlot(app, event)
            file = app.FullPath;
            interval = app.IntervalInSec.Value;
            length = app.LengthofVideos.Value;
            threshold = app.MinimumThresholdForFreezing.Value;
            
            framerate = 3.75;
            frame_end = length*framerate;
            interval_new = interval*framerate;
            
            velocityMatrix = [];
            distanceFromCenterMatrix = [];
            avgPlaceXMatrix = [];
            avgPlaceYMatrix = [];
            meanAreaMatrix = [];
            movementInPlaceMatrix = [];
            timeMatrix = [];
            if ~isempty(file) && ~isempty(interval) && ~isempty(length) && ~isempty(threshold)
                count = 0;
                for i = 1:interval_new:frame_end+interval_new
                    app.Status.Value = [app.Status.Value; strjoin({'Started Interval ' num2str(interval*count) 'sec'})];
                    finalMetrics = mouseBehaviorAnalysis(file, 0, i, i+interval_new, threshold);
                    app.Status.Value = [app.Status.Value; strjoin({'Finished Interval ' num2str(interval*count) 'sec'})];
                    count = count + 1;
                    
                    MeanVelocity = finalMetrics.MeanVelocity;
                    DistanceFromCenter = finalMetrics.DistanceFromCenter;
                    AveragePlacement = finalMetrics.AveragePlacement;
                    AveragePlacementX = AveragePlacement(:,1);
                    AveragePlacementY = AveragePlacement(:,2);
                    MeanArea = finalMetrics.MeanArea;
                    MovementInPlace = finalMetrics.MovementInPlace;
                    
                    velocityMatrix = [velocityMatrix; MeanVelocity];
                    distanceFromCenterMatrix = [distanceFromCenterMatrix; DistanceFromCenter];
                    avgPlaceXMatrix = [avgPlaceXMatrix; AveragePlacementX];
                    avgPlaceYMatrix = [avgPlaceYMatrix; AveragePlacementY];
                    meanAreaMatrix = [meanAreaMatrix; MeanArea];
                    movementInPlaceMatrix = [movementInPlaceMatrix; MovementInPlace];
                    
                    
                end
                
                for i=1:interval:length+interval
                    timeMatrix = [timeMatrix; i];
                end
            
                 figure, plot(timeMatrix,velocityMatrix)
                 xlabel('Time (sec)');
                 ylabel('Velocity (cm/sec)');
                 title('Velocity vs Time');
                 
                 figure, plot(timeMatrix,distanceFromCenterMatrix)
                 xlabel('Time (sec)');
                 ylabel('Distance From Center (cm)');
                 title('Distance From Center vs Time');
                 
                 figure, plot(avgPlaceXMatrix,avgPlaceYMatrix)
                 xlabel('Average X Placement (cm)');
                 ylabel('Average Y Placement (cm)');
                 title('Average Placement of Mice in Cage');
                 
                 figure, plot(timeMatrix,meanAreaMatrix)
                 xlabel('Time (sec)');
                 ylabel('Mean Area (cm^2)');
                 title('Mean Area vs Time');
                 
                 figure, plot(timeMatrix,movementInPlaceMatrix)
                 xlabel('Time (sec)');
                 ylabel('Movement In Place (sec)');
                 title('Movement in Place vs Time');
            end
        end

        % Button pushed function: ExporttoCSVButton
        function ExportToCSV(app, event)
            directory = dir([app.PathToDirectory '*.mov']);
            lengthVid = app.LengthofVideos.Value;
            intervalSeconds = app.IntervalInSec.Value;
            output_name = app.NameofOutput.Value;
            minThreshold = app.MinimumThresholdForFreezing.Value;
            
            if ~isempty(minThreshold) && ~isempty(directory) && ~isempty(lengthVid) && ~isempty(intervalSeconds) && ~isempty(output_name)
                for i=0:intervalSeconds:(lengthVid-intervalSeconds)
                    app.Status.Value = [app.Status.Value; strjoin({'Started Interval ' num2str(i) '-' num2str(i+intervalSeconds) 'sec'})];
                    analyze(directory, output_name, [num2str(i) '-' num2str(i+intervalSeconds) ' sec'], i, i+intervalSeconds, minThreshold);
                    app.Status.Value = [app.Status.Value; strjoin({'Finished Interval ' num2str(i) '-' num2str(i+intervalSeconds) ' sec'})];
                end
            end
        end

        % Button pushed function: PickVideoDirectoryforExportingButton
        function PickVideoDirectory(app, event)
            [file, path] = uigetfile('*.mov');
            app.PathToDirectory = path;
            app.FullPath = fullfile(path, file);
            app.DirectoryChosenLabel.Text = app.PathToDirectory;
        end

        % Button pushed function: ExportForMLButton
        function ExportForML(app, event)
            directory = dir([app.PathToDirectory '*.mov']);
            lengthVid = app.LengthofVideos.Value;
            intervalSeconds = app.IntervalInSec.Value;
            output_name = app.NameofOutput.Value;
            minThreshold = app.MinimumThresholdForFreezing.Value;
            
            if ~isempty(minThreshold) && ~isempty(directory) && ~isempty(lengthVid) && ~isempty(intervalSeconds) && ~isempty(output_name)
                for i=0:intervalSeconds:(lengthVid-intervalSeconds)
                    app.Status.Value = [app.Status.Value; strjoin({'Started Interval ' num2str(i) '-' num2str(i+intervalSeconds) 'sec'})];
                    analyzeForML(directory, output_name, i, i+intervalSeconds, minThreshold);
                    app.Status.Value = [app.Status.Value; strjoin({'Finished Interval ' num2str(i) '-' num2str(i+intervalSeconds) ' sec'})];
                end
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.ExporttoCSVButton = uibutton(app.UIFigure, 'push');
            app.ExporttoCSVButton.ButtonPushedFcn = createCallbackFcn(app, @ExportToCSV, true);
            app.ExporttoCSVButton.Text = 'Export to CSV';
            app.MouseVideoBehavioralAnalysisLabel = uilabel(app.UIFigure);
            app.MouseVideoBehavioralAnalysisLabel.Text = 'Mouse Video Behavioral Analysis';
            app.PickVideoDirectoryforExportingButton = uibutton(app.UIFigure, 'push');
            app.PickVideoDirectoryforExportingButton.ButtonPushedFcn = createCallbackFcn(app, @PickVideoDirectory, true);
            app.PickVideoDirectoryforExportingButton.Text = 'Pick Video (Directory for Exporting)';
            app.DirectoryChosenLabel = uilabel(app.UIFigure);
            app.DirectoryChosenLabel.Text = 'Directory Chosen';
            app.LengthofVideosinsecondsEditFieldLabel = uilabel(app.UIFigure);
            app.LengthofVideosinsecondsEditFieldLabel.Text = 'Length of Videos (in seconds)';
            app.LengthofVideos = uieditfield(app.UIFigure, 'numeric');
            app.LengthofVideos.Value = 100;
            app.IntervalinsecondsEditFieldLabel = uilabel(app.UIFigure);
            app.IntervalinsecondsEditFieldLabel.Text = 'Interval (in seconds)';
            app.IntervalInSec = uieditfield(app.UIFigure, 'numeric');
            app.IntervalInSec.Value = 2;
            app.NameofOutputFileifexportingLabel = uilabel(app.UIFigure);
            app.NameofOutputFileifexportingLabel.Text = 'Name of Output File (if exporting)';
            app.NameofOutput = uieditfield(app.UIFigure, 'text');
            app.Status = uitextarea(app.UIFigure);
            app.Status.Editable = 'off';
            app.MinimumThresholdForFreezingcmsecEditFieldLabel = uilabel(app.UIFigure);
            app.MinimumThresholdForFreezingcmsecEditFieldLabel.Text = 'Minimum Threshold For Freezing (cm/sec)';
            app.MinimumThresholdForFreezing = uieditfield(app.UIFigure, 'numeric');
            app.MinimumThresholdForFreezing.Value = 3;
            app.DisplayPlotButton = uibutton(app.UIFigure, 'push');
            app.DisplayPlotButton.ButtonPushedFcn = createCallbackFcn(app, @DisplayPlot, true);
            app.DisplayPlotButton.Text = 'Display Plot';
            app.ExportForMLButton = uibutton(app.UIFigure, 'push');
            app.ExportForMLButton.ButtonPushedFcn = createCallbackFcn(app, @ExportForML, true);
            app.ExportForMLButton.Text = 'Export For ML';
        end
    end

    methods (Access = public)

        % Construct app
        function app = MouseVideoBehavioralAnalysisGUI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end